classdef BCIapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BCIappUIFigure                  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadExperimentMenu              matlab.ui.container.Menu
        SaveExperimentMenu              matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        CurrentExperimentDetailsPanel   matlab.ui.container.Panel
        NameofExperimentLabel           matlab.ui.control.Label
        LastRecordingDateLabel          matlab.ui.control.Label
        LastCalibrationDateLabel        matlab.ui.control.Label
        ChannelsSelectedLeftLabel       matlab.ui.control.Label
        NameofExperimentAnswer          matlab.ui.control.Label
        LastRecordingDateAnswer         matlab.ui.control.Label
        LastCalibrationDateAnswer       matlab.ui.control.Label
        ChannelsSelectedLeftAnswer      matlab.ui.control.Label
        NumberofRecordingsLabel         matlab.ui.control.Label
        NumberofRecordingsAnswer        matlab.ui.control.Label
        ReadyLabel_4                    matlab.ui.control.Label
        ReadyLamp_home                  matlab.ui.control.Lamp
        CalibrationTab                  matlab.ui.container.Tab
        BaselineAxes                    matlab.ui.control.UIAxes
        ActionAxes                      matlab.ui.control.UIAxes
        DifferenceAxes                  matlab.ui.control.UIAxes
        CalculateButton                 matlab.ui.control.Button
        ReadyLabel_2                    matlab.ui.control.Label
        ReadyLamp_calibration           matlab.ui.control.Lamp
        RecordBaseline                  matlab.ui.control.StateButton
        RecordAction                    matlab.ui.control.StateButton
        RecordingTimesEditFieldLabel    matlab.ui.control.Label
        RecordingTimesBaselineEditField  matlab.ui.control.NumericEditField
        RecordingTimesEditField_2Label  matlab.ui.control.Label
        RecordingTimesActionEditField   matlab.ui.control.NumericEditField
        MuBandPassEditFieldLabel        matlab.ui.control.Label
        MuBandPassLow                   matlab.ui.control.NumericEditField
        MuBandPassHigh                  matlab.ui.control.NumericEditField
        SelectChannelsTab               matlab.ui.container.Tab
        TopoAxes                        matlab.ui.control.UIAxes
        LeftChannelsEditFieldLabel      matlab.ui.control.Label
        LeftChannelsEditField           matlab.ui.control.EditField
        SaveSelectionButton             matlab.ui.control.Button
        ReadyLabel                      matlab.ui.control.Label
        ReadyLamp_channels              matlab.ui.control.Lamp
        BCITab                          matlab.ui.container.Tab
        ExperimentSetupPanel            matlab.ui.container.Panel
        BCIselectionButtonGroup         matlab.ui.container.ButtonGroup
        CursorMovementButton            matlab.ui.control.RadioButton
        AnotherBCIButton                matlab.ui.control.RadioButton
        YetAnotherBCIButton             matlab.ui.control.RadioButton
        StartButton                     matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        GravitySliderLabel              matlab.ui.control.Label
        GravitySlider                   matlab.ui.control.Slider
        ParticipantIDEditFieldLabel     matlab.ui.control.Label
        ParticipantIDEditField          matlab.ui.control.EditField
        NumberofBlocksEditFieldLabel    matlab.ui.control.Label
        NumberofBlocksEditField         matlab.ui.control.NumericEditField
        SavingDirectoryLabel            matlab.ui.control.Label
        SelectButton                    matlab.ui.control.Button
        ExperimentNameEditFieldLabel    matlab.ui.control.Label
        ExperimentNameEditField         matlab.ui.control.EditField
        NumberofTrialsEditFieldLabel    matlab.ui.control.Label
        NumberofTrialsEditField         matlab.ui.control.NumericEditField
        PausebetweenTrialssecEditFieldLabel  matlab.ui.control.Label
        PausebetweenTrialsEditField     matlab.ui.control.NumericEditField
        PausebetweenBlockssecEditFieldLabel  matlab.ui.control.Label
        PausebetweenBlocksEditField     matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ReadyLabel_3                    matlab.ui.control.Label
        ReadyLamp_bci                   matlab.ui.control.Lamp
    end


    properties (Access = private)
        %List of Variables used throughout the app lifetime
        %Startup Data
        name_exp = 'None';
        last_rec_date = 'None';
        last_cal_date = 'None';
        channels_selected_left = {};
        left_channels = [];
        is_calibrated = false;
        is_channel_selected = false;
        is_baseline_recording = false;
        is_action_recording = false;
        is_calculation = false;
        is_ready = false;
        recordings = [];
        mu_baseline = [];
        mu_action = [];
        
        %Saved structure
        BCI_app = struct();
        Experiment = struct();
        Recording = struct();
        Data = struct();
        EEG = struct();
        EEG_info = struct();
        
        %Misc variables
        saving_dir = "";
        BP = [8,12];
        threshold = 0; 
    end

    %Helper methods
    methods (Access = private)
    
        %Animation to blink the lamp from one color to the other
        %Purely esthethic
        function blinking_lamp(app,number_blinks,color1,color2)
               for i = 1:number_blinks
                    app.ReadyLamp_home.Color = color1;
                    app.ReadyLamp_calibration.Color = color1;
                    app.ReadyLamp_channels.Color = color1;
                    app.ReadyLamp_bci.Color = color1;                    
                    pause(0.1)
                    app.ReadyLamp_home.Color = color2;
                    app.ReadyLamp_calibration.Color = color2;
                    app.ReadyLamp_channels.Color = color2;
                    app.ReadyLamp_bci.Color = color2;
                    pause(0.1)
               end            
        end
        
        %Check if the app is calibrated, if not will blink an give warning
        function result = check_calibration(app)
            if(app.is_calibrated == false)
               display('Need to calibrate first!');
               %Warning Animation
               white = [1,1,1];
               red = [1,0,0];
               blinking_lamp(app,3,white,red);
               result = false;
            else
                result = true;
            end            
            
        end
    
        %Check if channel were selected, if not will blink and give warning
        function result = check_channels(app)
            if(app.is_channel_selected == false)
               display('Need to select channels first!');
               %Warning Animation
               white = [1,1,1];
               red = [1,0,0];
               blinking_lamp(app,3,white,red);
               result = false;
            else
                result = true;
            end            
            
        end    
        
        % Check if more than one recording is going on at the same time
        function result = check_recording(app,value)
            if(value == 1)
               display('One recording at a time!');
               result = false;
            else
                result = true;
            end
        end
        
        %This will plot the three bar graph in the calibration and
        %will also plot the headplot in the channel selection
        function plotAllAxes(app)
            nbchan = 128;
            x = 1:nbchan;
            y = linspace(0,0,nbchan);
            %Plot the three graph in calibration
            bar(app.BaselineAxes,x,y);
            bar(app.ActionAxes,x,y);
            bar(app.DifferenceAxes,x,y);
            %Plot Topoplot in Selected Channels
            myImage = imread('helper_data/EGI.tif');
            imshow(myImage, 'Parent',app.TopoAxes);
        end
        
        %Load default value stored in EEG_info
        function load_default_EEG(app)
            temp = load('helper_data/EEG_info.mat');
            app.EEG_info = temp.EEG_info;
        end
        
        % Switch the lamp color from green to red or vice-versa
        function switch_lamp(app,action)
            green = [0,1,0];
            red = [1,0,0];
            app.is_ready = action;  
            if(action == true)
                app.ReadyLamp_home.Color = green;
                app.ReadyLamp_calibration.Color = green;
                app.ReadyLamp_channels.Color = green;
                app.ReadyLamp_bci.Color = green;
            else
                app.ReadyLamp_home.Color = red;
                app.ReadyLamp_calibration.Color = red;
                app.ReadyLamp_channels.Color = red;
                app.ReadyLamp_bci.Color = red;              
            end
        end
        
        % Check if the channel that were selected legit or not.
        % To do so it will check if the label entered fit those 
        % of the EGI-129 (info is in app.EEG_info.chanlocs)
        function result = are_channels_legit(app,channels,isLeft)
            for index = 1:size(channels,2)
                channel_to_find = string(channels(index));
                is_found = false;
                for i = 1:129
                    if(channel_to_find == app.EEG_info.chanlocs(1,i).labels)
                       is_found = true;
                       if(isLeft)
                          app.left_channels =  [app.left_channels ; i];                          
                       end
                    end
                end
                
                if(is_found == false)
                   result = false;
                   app.left_channels = [];
                   display(sprintf('did not find %s', channel_to_find));
                   return;
                end
            end
            result = true;
            return;
        end
        
        % Load the default BCI structure
        function load_bci_struct(app)
            struct_blueprint = load([pwd, '/helper_data/BCI_app.mat']);
            app.BCI_app = struct_blueprint.BCI_app;
            app.Experiment = app.BCI_app.experiment;
            app.Recording = app.BCI_app.recording;
            app.Data = app.BCI_app.data;
            app.EEG = app.BCI_app.eeg;
        end
        
        %Update the visual in every part of the application
        function update_GUI(app)
            %Update the saving struct
            app.BCI_app.experiment = app.Experiment;
            app.Recording = app.BCI_app.recording;
            app.Data = app.BCI_app.data;
            app.EEG = app.BCI_app.eeg;
            
            %Startup Data
            app.name_exp = app.Experiment.name;
            app.last_rec_date = app.Experiment.last_record;
            app.last_cal_date = app.Experiment.last_calibration;
            app.channels_selected_left = app.Experiment.channels_left;
            app.left_channels = app.Experiment.left_channels;
            app.is_calibrated = app.Experiment.params.is_calibrated;
            app.is_channel_selected = app.Experiment.params.is_channel_selected;
            app.is_baseline_recording = app.Experiment.params.is_baseline_recording;
            app.is_action_recording = app.Experiment.params.is_action_recording;
            app.is_calculation = app.Experiment.params.is_calculation;
            app.is_ready = app.Experiment.params.is_ready;
            app.recordings = app.Experiment.recordings;
            
            % Experiment specific data
            app.NameofExperimentAnswer.Text = app.name_exp;
            app.LastRecordingDateAnswer.Text = app.last_rec_date;
            app.LastCalibrationDateAnswer.Text = app.last_cal_date;
            app.ChannelsSelectedLeftAnswer.Text = strjoin(app.channels_selected_left);
            app.LeftChannelsEditField.Value =  strjoin(app.channels_selected_left);
            
            
            if(size(app.Experiment.recordings,2) ~= 0)
               Recording = app.Experiment.recordings(1,size(app.Experiment.recordings,2));
               app.GravitySlider.Value = Recording.gravity;
               app.ParticipantIDEditField.Value = Recording.participant_id;
               app.NumberofBlocksEditField.Value = Recording.nb_blocks;
               app.ExperimentNameEditField.Value = app.name_exp;
               app.NumberofTrialsEditField.Value = Recording.nb_trials;
               app.PausebetweenTrialsEditField.Value = Recording.pause_trials;
               app.PausebetweenBlocksEditField.Value = Recording.pause_blocks;
               app.saving_dir = Recording.saving_dir;
               app.ThresholdEditField.Value = Recording.threshold;
            end
            
            app.NumberofRecordingsAnswer.Text = string(size(app.Experiment.recordings,2));
            color = [1,0,0];
            if(app.is_ready == true)
                color = [0,1,0];
            end
            app.ReadyLamp_home.Color = color;
            app.ReadyLamp_calibration.Color = color;
            app.ReadyLamp_channels.Color = color;
            app.ReadyLamp_bci.Color = color;
            %plot default stuff in all plot
            plotAllAxes(app);
        end
    
    
        % Save the experiment parameters in the relevant structure
        function save_experiment(app)
            app.Experiment.name = app.name_exp;
            app.Experiment.last_record = app.last_rec_date;
            app.Experiment.last_calibration = app.last_cal_date;
            app.Experiment.channels_left = app.channels_selected_left;
            app.Experiment.left_channels = app.left_channels;
            app.Experiment.params.is_calibrated = app.is_calibrated;
            app.Experiment.params.is_channel_selected = app.is_channel_selected;
            app.Experiment.params.is_baseline_recording = app.is_baseline_recording;
            app.Experiment.params.is_action_recording = app.is_action_recording;
            app.Experiment.params.is_calculation = app.is_calculation;
            app.Experiment.params.is_ready = app.is_ready;
        end
        
        %Start the amplifier
        function setup_recording(app)
            ampstart;
        end
    
        %Stop the amplifier
        function clean_recording(app)
           ampstop;
        end
        
        %Record some data with the amp
        function data = record(app,time)
            data = ampcollect(time*1000);
            assignin('base','EEG_data',data);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            disp('-------------------------------------------------------------------------------------------------------');
            disp('Welcome to BCIapp: if there is a problem 514-792-9224 or send me an email: yacine.mahdid@mail.mcgill.ca'); 
            disp('-------------------------------------------------------------------------------------------------------');
            load_default_EEG(app);
            load_bci_struct(app);
            update_GUI(app);
            ampinit('10.10.10.51');    
            ampon;
            
        end

        % Menu selected function: LoadExperimentMenu
        function LoadExperimentMenuSelected(app, event)
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                disp('User selected Cancel');
                return;
            end
            disp(['User loaded ', fullfile(path,file)]);
            new_experiment = load(fullfile(path,file));
            if(~isfield(new_experiment,'Experiment'))
                disp('The loaded data is of wrong type');
                return;
            end
        
            new_experiment = new_experiment.Experiment;
            disp('The loaded data is of right type');
            app.Experiment = new_experiment;
            update_GUI(app);
            

        end

        % Menu selected function: SaveExperimentMenu
        function SaveExperimentMenuSelected(app, event)
            [file,path] = uiputfile('*.mat');
            if isequal(file,0)
                disp('User selected Cancel');
                return;
            end
            app.saving_dir = path;
            disp(['User want to save ', fullfile(path,file)]);
            Experiment = app.Experiment;
            save(fullfile(path,file), 'Experiment');
            disp('Experiment is saved!');
        end

        % Button pushed function: SaveSelectionButton
        function SaveSelectionButtonPushed(app, event)
            left = app.LeftChannelsEditField.Value;
            left = strsplit(left,',');
            left = strtrim(left);
            app.channels_selected_left = "";
            app.left_channels = [];
            if(isempty(left))
               display('Please enter left channels');
               switch_lamp(app,false);
            elseif(~app.is_calibrated)
               display('Please calibrate first');
               switch_lamp(app,false);                
            elseif(are_channels_legit(app,left,1))
                display('Ready for BCI');
                app.channels_selected_left = left;
                app.is_channel_selected = true;
                
                ratio = app.mu_baseline-app.mu_action;
                left_index = app.left_channels;
                app.ThresholdEditField.Value = app.mu_baseline(left_index) - (ratio(left_index)/2);
                switch_lamp(app,true);
            else
                display('Channels are incorect!');
                blinking_lamp(app,3,[1,1,1],[1,0,0])
                switch_lamp(app,false);
            end
            save_experiment(app);
            update_GUI(app);
            bar(app.BaselineAxes,app.mu_baseline([7,13,29,30,31,35,36,37,41,42,47]));
            bar(app.ActionAxes,app.mu_action([7,13,29,30,31,35,36,37,41,42,47]));
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %Here we need to check if the left and right channels are saved
            if(check_channels(app) == false)
                return
            end       
           
            %Here we get the data and we start the BCI 
            gravity = app.GravitySlider.Value;
            participant_id = app.ParticipantIDEditField.Value;
            nb_blocks = app.NumberofBlocksEditField.Value;
            exp_name = app.ExperimentNameEditField.Value;
            nb_trials = app.NumberofTrialsEditField.Value;
            pause_trials = app.PausebetweenTrialsEditField.Value;
            pause_blocks = app.PausebetweenBlocksEditField.Value;
            
            %SHould print everything here to know what happened
            disp(['gravity: ',string(gravity)]);
            disp(['id: ', participant_id]);
            disp(['Name ',string(exp_name)]);
            disp(['nb blocks: ',string(nb_blocks)]);
            disp(['nb trials: ',string(nb_trials)]);
            disp(['pause trials: ',string(pause_trials)]);
            disp(['pause blocks: ',string(pause_blocks)]);
            if(gravity == 0 || strcmp(participant_id,"") == 1 || strcmp(exp_name,"") == 1 || ...
                    nb_blocks <= 0 || nb_trials <= 0 || pause_trials <= 0 || pause_blocks <= 0 )
               disp("BCI Form is not well formed");
               return;
            else
                disp("BCI Form is well formed");
            end
            
            Recording = struct();
            Recording.date = date;
            Recording.participant_id =  participant_id;
            Recording.nb_trials = nb_trials;
            Recording.nb_blocks = nb_blocks;
            Recording.pause_trials = pause_trials;
            Recording.pause_blocks = pause_blocks;
            Recording.gravity = gravity;
            Recording.saving_dir = app.saving_dir
            Recording.threshold = app.ThresholdEditField.Value;
            Recording.data = struct();
            app.Experiment.recordings = [app.Experiment.recordings Recording];
            disp(['Current size:',string(size(app.Experiment.recordings,2))])
            app.name_exp = exp_name;
            app.last_rec_date = Recording.date;
            
            save_experiment(app);
            trials = nb_trials;
            trialPauseLength = pause_trials;
            blocks = nb_blocks;
            blockPauseLength = pause_blocks;
            left_chan = app.left_channels;
            saving_file_name = strcat(app.saving_dir,'/',exp_name,'_',participant_id);
            
            %This is the Psychtoolbox function we use to start the app
            cursorBCI(trials,trialPauseLength,blocks,blockPauseLength,gravity,left_chan,saving_file_name,app.BP,app.ThresholdEditField.Value);
            update_GUI(app);
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            path = uigetdir;
            if isequal(path,0)
                disp('User selected Cancel');
                return;
            else
                disp(['User selected folder: ', path]);
                app.saving_dir = path;
            end            
        end

        % Value changed function: RecordBaseline
        function RecordBaselineValueChanged(app, event)
            baseline_value = app.RecordBaseline.Value;
            action_value = app.RecordAction.Value;
            BP = [app.MuBandPassLow.Value,app.MuBandPassHigh.Value];
            if(check_recording(app,action_value) == false)
               app.RecordBaseline.Value = 0;
               return;
            elseif(baseline_value == 1)
                display('------------------------------------------------------------------------');
                display('Recording baseline do not close your eyes and please look at the screen.');
                display('------------------------------------------------------------------------');                
                %Record and calculate the mu rhythm
                setup_recording(app);
                EEG_data = record(app,app.RecordingTimesBaselineEditField.Value);
                EEG_data = bpfilter(3,15,1000,EEG_data);
                for i = 1:size(EEG_data,2)
                    curr_mu = getMuRhythm(EEG_data(:,i),0,BP);
                    app.mu_baseline(i) = curr_mu;
                end
                
                %Plot the mu-rhythms
                bar(app.BaselineAxes,app.mu_baseline([7,13,29,30,31,35,36,37,41,42,47]));
                xticks(app.BaselineAxes,[1 2 3 4 5 6 7 8 9 10 11 ]);
                xticklabels(app.BaselineAxes,{'7','13','29','30','31','35','36','37','41','42','47'})
                getMuRhythm(EEG_data,1,BP);
                app.BP = BP;
                clean_recording(app);
                app.RecordBaseline.Value = 0;
                display('Baseline Recording done!');
                app.is_baseline_recording = true;
            end
        end

        % Value changed function: RecordAction
        function RecordActionValueChanged(app, event)
            baseline_value = app.RecordBaseline.Value;
            action_value = app.RecordAction.Value;
            BP = [app.MuBandPassLow.Value,app.MuBandPassHigh.Value];
            if(check_recording(app,baseline_value) == false)
               app.RecordAction.Value = 0;
               return;
            elseif(action_value == 1)
                display('-----------------------------------------------------------------------------------------------------');
                display('Recording action, please keep your eyes open and think about contracting the right part of your body.');
                display('-----------------------------------------------------------------------------------------------------');                
                
                %Record and calculate the mu-rhythm
                setup_recording(app);
                EEG_data = record(app,app.RecordingTimesActionEditField.Value);
                EEG_data = bpfilter(3,15,1000,EEG_data);
                for i = 1:size(EEG_data,2)
                    app.mu_action(i) = getMuRhythm(EEG_data(:,i),0,BP);
                end
                
                %Plot the mu-rhythm
                bar(app.ActionAxes,app.mu_action([7,13,29,30,31,35,36,37,41,42,47]));
                xticks(app.ActionAxes,[1 2 3 4 5 6 7 8 9 10 11]);
                xticklabels(app.ActionAxes,{'7','13','29','30','31','35','36','37','41','42','47'})
                
                getMuRhythm(EEG_data,1,BP);
                app.BP = BP;
                clean_recording(app);
                app.RecordAction.Value = 0;
                display('Stopped Action recording!');
                app.is_action_recording = true;
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            baseline_value = app.RecordBaseline.Value;
            action_value = app.RecordAction.Value;
            is_baseline_recorded = app.is_baseline_recording;
            is_action_recorded = app.is_action_recording;
            if(baseline_value+action_value > 0)
                display('Stop recording before calculating!') 
                return
            elseif(~is_baseline_recorded || ~is_action_recorded)
                 display('Record Baseline and Action before calculating!')
                 return;
            end
            display('------------------------------------------------------------------------------------------');
            display('Calculating the difference between baseline and action (POSITIVE IS GOOD, NEGATIVE IS BAD)');
            display('------------------------------------------------------------------------------------------');
            %Calculate the difference between the baseline and the action mu rhythm
            ratio = app.mu_baseline - app.mu_action;
            saved_ratio = ratio;
            bar(app.DifferenceAxes,ratio([7,13,29,30,31,35,36,37,41,42,47]));
            xticks(app.DifferenceAxes,[1 2 3 4 5 6 7 8 9 10 11]);
            xticklabels(app.DifferenceAxes,{'7','13','29','30','31','35','36','37','41','42','47'})
            for i = 1:length(ratio)
                [max_y,idx] = max(ratio);
                if(ismember(idx,[7,13,29,30,31,35,36,37,41,42,47]))
                    display(['E',num2str(idx),' is at ',num2str(max_y)]);
                end
                ratio(idx) = -10000000;
            end

            app.is_calibrated = true;
            app.is_calculation = true;
        end

        % Close request function: BCIappUIFigure
        function BCIappUIFigureCloseRequest(app, event)
           %Need to do the following to close the amp
           ampdeinit;
           clear ampsrv;
           delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BCIappUIFigure = uifigure;
            app.BCIappUIFigure.Name = 'BCIapp';
            app.BCIappUIFigure.CloseRequestFcn = createCallbackFcn(app, @BCIappUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.BCIappUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadExperimentMenu = uimenu(app.FileMenu);
            app.LoadExperimentMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadExperimentMenuSelected, true);
            app.LoadExperimentMenu.Text = 'Load Experiment';
            app.SaveExperimentMenu = uimenu(app.FileMenu);
            app.SaveExperimentMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveExperimentMenuSelected, true);
            app.SaveExperimentMenu.Text = 'Save Experiment';
            app.TabGroup = uitabgroup(app.BCIappUIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.CurrentExperimentDetailsPanel = uipanel(app.HomeTab);
            app.CurrentExperimentDetailsPanel.Title = 'Current Experiment Details';
            app.NameofExperimentLabel = uilabel(app.CurrentExperimentDetailsPanel);
            app.NameofExperimentLabel.Text = 'Name of Experiment';
            app.LastRecordingDateLabel = uilabel(app.CurrentExperimentDetailsPanel);
            app.LastRecordingDateLabel.Text = 'Last Recording Date';
            app.LastCalibrationDateLabel = uilabel(app.CurrentExperimentDetailsPanel);
            app.LastCalibrationDateLabel.Text = 'Last Calibration Date';
            app.ChannelsSelectedLeftLabel = uilabel(app.CurrentExperimentDetailsPanel);
            app.ChannelsSelectedLeftLabel.Text = 'Channels Selected (Left)';
            app.NameofExperimentAnswer = uilabel(app.CurrentExperimentDetailsPanel);
            app.NameofExperimentAnswer.Text = '';
            app.LastRecordingDateAnswer = uilabel(app.CurrentExperimentDetailsPanel);
            app.LastRecordingDateAnswer.Text = '';
            app.LastCalibrationDateAnswer = uilabel(app.CurrentExperimentDetailsPanel);
            app.LastCalibrationDateAnswer.Text = '';
            app.ChannelsSelectedLeftAnswer = uilabel(app.CurrentExperimentDetailsPanel);
            app.ChannelsSelectedLeftAnswer.Text = '';
            app.NumberofRecordingsLabel = uilabel(app.CurrentExperimentDetailsPanel);
            app.NumberofRecordingsLabel.Text = 'Number of Recordings';
            app.NumberofRecordingsAnswer = uilabel(app.CurrentExperimentDetailsPanel);
            app.NumberofRecordingsAnswer.Text = '';
            app.ReadyLabel_4 = uilabel(app.HomeTab);
            app.ReadyLabel_4.Text = 'Ready';
            app.ReadyLamp_home = uilamp(app.HomeTab);
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.BaselineAxes = uiaxes(app.CalibrationTab);
            title(app.BaselineAxes, 'Mu Rhythm Baseline')
            xlabel(app.BaselineAxes, 'Channels')
            ylabel(app.BaselineAxes, 'Power')
            app.ActionAxes = uiaxes(app.CalibrationTab);
            title(app.ActionAxes, 'Mu Rhythm Action')
            xlabel(app.ActionAxes, 'Channels')
            ylabel(app.ActionAxes, 'Power')
            app.DifferenceAxes = uiaxes(app.CalibrationTab);
            title(app.DifferenceAxes, 'Mu Rhythm Difference')
            xlabel(app.DifferenceAxes, 'Channels')
            ylabel(app.DifferenceAxes, 'Ratio')
            app.CalculateButton = uibutton(app.CalibrationTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ReadyLabel_2 = uilabel(app.CalibrationTab);
            app.ReadyLabel_2.Text = 'Ready';
            app.ReadyLamp_calibration = uilamp(app.CalibrationTab);
            app.RecordBaseline = uibutton(app.CalibrationTab, 'state');
            app.RecordBaseline.ValueChangedFcn = createCallbackFcn(app, @RecordBaselineValueChanged, true);
            app.RecordBaseline.Text = 'Record';
            app.RecordAction = uibutton(app.CalibrationTab, 'state');
            app.RecordAction.ValueChangedFcn = createCallbackFcn(app, @RecordActionValueChanged, true);
            app.RecordAction.Text = 'Record';
            app.RecordingTimesEditFieldLabel = uilabel(app.CalibrationTab);
            app.RecordingTimesEditFieldLabel.Text = 'Recording Time (s)';
            app.RecordingTimesBaselineEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.RecordingTimesBaselineEditField.Limits = [0 Inf];
            app.RecordingTimesBaselineEditField.Value = 1;
            app.RecordingTimesEditField_2Label = uilabel(app.CalibrationTab);
            app.RecordingTimesEditField_2Label.Text = 'Recording Time (s)';
            app.RecordingTimesActionEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.RecordingTimesActionEditField.Limits = [0 Inf];
            app.RecordingTimesActionEditField.Value = 1;
            app.MuBandPassEditFieldLabel = uilabel(app.CalibrationTab);
            app.MuBandPassEditFieldLabel.Text = 'Mu Band Pass';
            app.MuBandPassLow = uieditfield(app.CalibrationTab, 'numeric');
            app.MuBandPassLow.Limits = [0 Inf];
            app.MuBandPassLow.Value = 8;
            app.MuBandPassHigh = uieditfield(app.CalibrationTab, 'numeric');
            app.MuBandPassHigh.Limits = [0 Inf];
            app.MuBandPassHigh.Value = 12;
            app.SelectChannelsTab = uitab(app.TabGroup);
            app.SelectChannelsTab.Title = 'Select Channels';
            app.TopoAxes = uiaxes(app.SelectChannelsTab);
            title(app.TopoAxes, 'EGI Channels')
            app.LeftChannelsEditFieldLabel = uilabel(app.SelectChannelsTab);
            app.LeftChannelsEditFieldLabel.Text = 'Left Channels';
            app.LeftChannelsEditField = uieditfield(app.SelectChannelsTab, 'text');
            app.SaveSelectionButton = uibutton(app.SelectChannelsTab, 'push');
            app.SaveSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSelectionButtonPushed, true);
            app.SaveSelectionButton.Text = 'Save Selection';
            app.ReadyLabel = uilabel(app.SelectChannelsTab);
            app.ReadyLabel.Text = 'Ready';
            app.ReadyLamp_channels = uilamp(app.SelectChannelsTab);
            app.BCITab = uitab(app.TabGroup);
            app.BCITab.Title = 'BCI';
            app.ExperimentSetupPanel = uipanel(app.BCITab);
            app.ExperimentSetupPanel.Title = 'Experiment Setup';
            app.BCIselectionButtonGroup = uibuttongroup(app.ExperimentSetupPanel);
            app.BCIselectionButtonGroup.Title = 'BCI selection';
            app.CursorMovementButton = uiradiobutton(app.BCIselectionButtonGroup);
            app.CursorMovementButton.Text = 'Cursor Movement';
            app.CursorMovementButton.Value = true;
            app.AnotherBCIButton = uiradiobutton(app.BCIselectionButtonGroup);
            app.AnotherBCIButton.Text = 'Another BCI';
            app.YetAnotherBCIButton = uiradiobutton(app.BCIselectionButtonGroup);
            app.YetAnotherBCIButton.Text = 'Yet Another BCI';
            app.StartButton = uibutton(app.ExperimentSetupPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Panel = uipanel(app.ExperimentSetupPanel);
            app.Panel.Title = 'Panel';
            app.GravitySliderLabel = uilabel(app.Panel);
            app.GravitySliderLabel.Text = 'Gravity';
            app.GravitySlider = uislider(app.Panel);
            app.GravitySlider.Limits = [1 100];
            app.GravitySlider.Value = 1;
            app.ParticipantIDEditFieldLabel = uilabel(app.Panel);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDEditField = uieditfield(app.Panel, 'text');
            app.NumberofBlocksEditFieldLabel = uilabel(app.Panel);
            app.NumberofBlocksEditFieldLabel.Text = 'Number of Blocks';
            app.NumberofBlocksEditField = uieditfield(app.Panel, 'numeric');
            app.SavingDirectoryLabel = uilabel(app.Panel);
            app.SavingDirectoryLabel.Text = 'Saving Directory';
            app.SelectButton = uibutton(app.Panel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ExperimentNameEditFieldLabel = uilabel(app.Panel);
            app.ExperimentNameEditFieldLabel.Text = 'Experiment Name';
            app.ExperimentNameEditField = uieditfield(app.Panel, 'text');
            app.NumberofTrialsEditFieldLabel = uilabel(app.Panel);
            app.NumberofTrialsEditFieldLabel.Text = 'Number of Trials';
            app.NumberofTrialsEditField = uieditfield(app.Panel, 'numeric');
            app.PausebetweenTrialssecEditFieldLabel = uilabel(app.Panel);
            app.PausebetweenTrialssecEditFieldLabel.Text = 'Pause between Trials (sec)';
            app.PausebetweenTrialsEditField = uieditfield(app.Panel, 'numeric');
            app.PausebetweenBlockssecEditFieldLabel = uilabel(app.Panel);
            app.PausebetweenBlockssecEditFieldLabel.Text = 'Pause between Blocks (sec)';
            app.PausebetweenBlocksEditField = uieditfield(app.Panel, 'numeric');
            app.ThresholdEditFieldLabel = uilabel(app.Panel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.Panel, 'numeric');
            app.ThresholdEditField.Value = 1;
            app.ReadyLabel_3 = uilabel(app.BCITab);
            app.ReadyLabel_3.Text = 'Ready';
            app.ReadyLamp_bci = uilamp(app.BCITab);
        end
    end

    methods (Access = public)

        % Construct app
        function app = BCIapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BCIappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BCIappUIFigure)
        end
    end
end